classdef nightview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadanimageButton           matlab.ui.control.Button
        ImageAxes                   matlab.ui.control.UIAxes
        STARTButton                 matlab.ui.control.Button
        BilateralSwitchLabel        matlab.ui.control.Label
        BilateralSwitch             matlab.ui.control.ToggleSwitch
        CANSwitchLabel              matlab.ui.control.Label
        CANSwitch                   matlab.ui.control.ToggleSwitch
        SharpenSwitchLabel          matlab.ui.control.Label
        SharpenSwitch               matlab.ui.control.ToggleSwitch
        EnhanceContrastSwitchLabel  matlab.ui.control.Label
        EnhanceContrastSwitch       matlab.ui.control.ToggleSwitch
        AlleffectsonSwitchLabel     matlab.ui.control.Label
        AlleffectsonSwitch          matlab.ui.control.ToggleSwitch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadanimageButton
        function LoadanimageButtonPushed(app, event)
           % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
            
            global bilateral;
            global CAN;
            global sharpen;
            global contrast;
            
            
            bilateral = 0;
            CAN = 0;
            sharpen = 0;
            contrast = 0;
            
          
        end
        
        
       function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end


        end

        % Value changed function: BilateralSwitch
        function BilateralSwitchValueChanged(app, event)
            value = app.BilateralSwitch.Value;
            global bilateral;
            if strcmp(value,'On')
                bilateral = 1; 
            else
                bilateral = 0;
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
        global bilateral;
        global CAN;
        global sharpen;
        global contrast;
        
        % Display uigetfile dialog
        filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
        [f, p] = uigetfile(filterspec);
        % Make sure user didn't cancel uigetfile dialog
        if (ischar(p))
           fname = [p f];
           updateimage(app, fname);
        end
        
        exts = {'.jpg','.png'};
        filePathNames = strcat(p,f);
        testImages = imageDatastore(filePathNames,'FileExtensions',exts);
        indx = 1; % Index of image to read from the test image datastore
        Ireference = readimage(testImages,indx);
        Ireference = im2uint8(Ireference);
        
        if bilateral == 1
            degreeOfSmoothing = var(double(Ireference(:)));
            Ibilat = imbilatfilt(Ireference,degreeOfSmoothing);
            imshow(Ibilat)
            title('Denoised Image Obtained Using Bilateral Filtering')
            fprintf("bilateral done!\n");
        else
            fprintf("bilateral failed\n");
        end
        
        if CAN ==1
            load('trainedOperatorLearning-Epoch-181.mat');
            Iapprox = activations(net,Ireference,'FinalRegressionLayer');
            Iapprox = rescale(Iapprox);
            Iapprox = im2uint8(Iapprox);
            imshow(Iapprox)
            title('Denoised Image Obtained Using Multiscale CAN')
%             fprintf("approx done!\n");
            
        end
        
        if sharpen == 1
            src = Ireference;
            SrcYcbcr = rgb2ycbcr(src);
            SrcY = SrcYcbcr(:,:,1);
            size = 3;
            sigma = 1;
            amount = 1.5;
            threshold = 15;
            gaussFilter = fspecial('gaussian',[size,size],sigma);
            SrcY_lf = imfilter(SrcY,gaussFilter,'symmetric');
            
            SrcY_hf = SrcY - SrcY_lf;
            imLabel = (SrcY_hf>threshold);
            imLabel = uint8(imLabel);
            imDstY = uint8(SrcY+amount*SrcY_hf.*imLabel);
            SrcYcbcr(:,:,1) = imDstY;
            imDst = ycbcr2rgb(uint8(SrcYcbcr));
            figure,imshow(imDst);
            title('sharpened');
            fprintf("sharpen done!\n");
        else
            fprintf("sharpen failed!\n");
        end
        
        if contrast == 1
            src = Ireference;
            Lab = rgb2lab(src);
            L = Lab(:,:,1)./100;
            L = adapthisteq(L,'NumTiles',[8,8],'ClipLimit',0.0001);
            Lab(:,:,1) = L*100;
            super_sce3 = lab2rgb(Lab);
            figure,imshow(super_sce3);
            title('contrast enhanced');
            fprintf("contrast done!\n");
        else
            fprintf("contrast failed!\n");
        end


        end

        % Value changed function: CANSwitch
        function CANSwitchValueChanged(app, event)
            value = app.CANSwitch.Value;
            global CAN;
            if strcmp(value,'On')
                CAN = 1; 
            else
                CAN = 0;
            end
        end

        % Value changed function: SharpenSwitch
        function SharpenSwitchValueChanged(app, event)
            value = app.SharpenSwitch.Value;
            global sharpen;
            if strcmp(value,'On')
                sharpen = 1; 
            else
                sharpen = 0;
            end
        end

        % Value changed function: EnhanceContrastSwitch
        function EnhanceContrastSwitchValueChanged(app, event)
            value = app.EnhanceContrastSwitch.Value;
            global contrast;
            if strcmp(value,'On')
                contrast = 1; 
            else
                contrast = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadanimageButton = uibutton(app.UIFigure, 'push');
            app.LoadanimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadanimageButtonPushed, true);
            app.LoadanimageButton.Text = 'Load an image';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.BilateralSwitchLabel = uilabel(app.UIFigure);
            app.BilateralSwitchLabel.Text = 'Bilateral';
            app.BilateralSwitch = uiswitch(app.UIFigure, 'toggle');
            app.BilateralSwitch.ValueChangedFcn = createCallbackFcn(app, @BilateralSwitchValueChanged, true);
            app.CANSwitchLabel = uilabel(app.UIFigure);
            app.CANSwitchLabel.Text = 'CAN';
            app.CANSwitch = uiswitch(app.UIFigure, 'toggle');
            app.CANSwitch.ValueChangedFcn = createCallbackFcn(app, @CANSwitchValueChanged, true);
            app.SharpenSwitchLabel = uilabel(app.UIFigure);
            app.SharpenSwitchLabel.Text = 'Sharpen';
            app.SharpenSwitch = uiswitch(app.UIFigure, 'toggle');
            app.SharpenSwitch.ValueChangedFcn = createCallbackFcn(app, @SharpenSwitchValueChanged, true);
            app.EnhanceContrastSwitchLabel = uilabel(app.UIFigure);
            app.EnhanceContrastSwitchLabel.Text = 'EnhanceContrast';
            app.EnhanceContrastSwitch = uiswitch(app.UIFigure, 'toggle');
            app.EnhanceContrastSwitch.ValueChangedFcn = createCallbackFcn(app, @EnhanceContrastSwitchValueChanged, true);
            app.AlleffectsonSwitchLabel = uilabel(app.UIFigure);
            app.AlleffectsonSwitchLabel.Text = 'All effects on';
            app.AlleffectsonSwitch = uiswitch(app.UIFigure, 'toggle');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nightview

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end